classdef a20412_165400app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        AnswerLabel              matlab.ui.control.Label
        CalculateButton          matlab.ui.control.Button
        MassInputEditField       matlab.ui.control.NumericEditField
        MassInputEditFieldLabel  matlab.ui.control.Label
        MaterialDropDown         matlab.ui.control.DropDown
        MaterialDropDownLabel    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MaterialDropDown.Items = {'-- Select a Material --','Brass','Carbon', 'Ice', 'Acrylic','Steel','Graphite'};
            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            m = app.MassInputEditField.Value;
            matSel = app.MaterialDropDown.Value;
            g = 9.81;

            switch matSel
                case 'Brass'
                    us = 0.35;
                case 'Carbon'
                    us = 0.14;
                case 'Ice'
                    us = 0.03;
                case 'Acrylic'
                    us = 0.45;
                case 'Steel'
                    us = 0.6;
                case 'Graphite'
                    us = 0.1;
            end

            force = m*us*g;
app.AnswerLabel.Text = ['The force is ', num2str(force), ' Newtons'];
           

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MaterialDropDownLabel = uilabel(app.UIFigure);
            app.MaterialDropDownLabel.Text = 'Material';
            app.MaterialDropDown = uidropdown(app.UIFigure);
            app.MaterialDropDown.Items = {'-- Select Material --', 'Brass', 'Carbon', 'Ice', 'Acrylic', 'Steel', 'Graphite'};
            app.MaterialDropDown.Value = '-- Select Material --';
            app.MassInputEditFieldLabel = uilabel(app.UIFigure);
            app.MassInputEditFieldLabel.Text = 'Mass Input';
            app.MassInputEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.AnswerLabel = uilabel(app.UIFigure);
            app.AnswerLabel.Text = 'Answer';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = a20412_165400app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end